classdef interphase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ExportButton        matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
        ParameterListPanel  matlab.ui.container.Panel
        mmHg1Label_2        matlab.ui.control.Label
        RULgLabel           matlab.ui.control.Label
        gLabel              matlab.ui.control.Label
        hr1Label            matlab.ui.control.Label
        LLabel_2            matlab.ui.control.Label
        hrLabel_3           matlab.ui.control.Label
        RULabel             matlab.ui.control.Label
        bpmLabel            matlab.ui.control.Label
        mmHgLabel_2         matlab.ui.control.Label
        hrLabel_2           matlab.ui.control.Label
        hrLabel             matlab.ui.control.Label
        mmHg1Label          matlab.ui.control.Label
        mmHgLabel           matlab.ui.control.Label
        LLabel              matlab.ui.control.Label
        EditField_m         matlab.ui.control.NumericEditField
        EditField_D         matlab.ui.control.NumericEditField
        EditField_gamma     matlab.ui.control.NumericEditField
        EditField_V_c       matlab.ui.control.NumericEditField
        EditField_T         matlab.ui.control.NumericEditField
        EditField_TPR_eq    matlab.ui.control.NumericEditField
        EditField_HR_eq     matlab.ui.control.NumericEditField
        EditField_MAP_eq    matlab.ui.control.NumericEditField
        EditField_T_D       matlab.ui.control.NumericEditField
        EditField_tau       matlab.ui.control.NumericEditField
        EditField_beta      matlab.ui.control.NumericEditField
        EditField_alpha     matlab.ui.control.NumericEditField
        EditField_RAP       matlab.ui.control.NumericEditField
        EditField_SV        matlab.ui.control.NumericEditField
        betaLabel           matlab.ui.control.Label
        mLabel              matlab.ui.control.Label
        DLabel              matlab.ui.control.Label
        gammaLabel          matlab.ui.control.Label
        V_cLabel            matlab.ui.control.Label
        TLabel              matlab.ui.control.Label
        TPR_eqLabel         matlab.ui.control.Label
        HR_eqLabel          matlab.ui.control.Label
        MAP_eqLabel         matlab.ui.control.Label
        T_DLabel            matlab.ui.control.Label
        tau_12Label         matlab.ui.control.Label
        alphaLabel          matlab.ui.control.Label
        RAPLabel            matlab.ui.control.Label
        SVLabel             matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        ResultsTable        matlab.ui.control.Table
        HRAxes              matlab.ui.control.UIAxes
        TPRAxes             matlab.ui.control.UIAxes
        MAPAxes             matlab.ui.control.UIAxes
        CpAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SV
        RAP
        a
        b
        tau
        T_D
        MAP_eq
        HR_eq
        TPR_eq
        T
        V_c
        g
        D
        m
        SimulationOutput
    end
    
    methods (Access = private)
        
        function params = getAppParameters(app)
            % Run the parameter file
            run('Project2024_params_Group3.m');
            
            params.SV = SV;
            params.RAP = RAP;
            params.a = a;
            params.b = b;
            params.tau = tau_1;
            params.T_D = T_D;
            params.MAP_eq = MAP_eq;
            params.HR_eq = HR_eq;
            params.TPR_eq = TPR_eq;
            params.T = T;
            params.V_c = V_c;
            params.g = g;
            params.D = D;
            params.m = m;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load default parameters from the parameter file
            params = app.getAppParameters();

            app.SV = params.SV;
            app.RAP = params.RAP;
            app.a = params.a;
            app.b = params.b;
            app.tau = params.tau;
            app.T_D = params.T_D;
            app.MAP_eq = params.MAP_eq;
            app.HR_eq = params.HR_eq;
            app.TPR_eq = params.TPR_eq;
            app.T = params.T;
            app.V_c = params.V_c;
            app.g = params.g;
            app.D = params.D;
            app.m = params.m;

            app.EditField_SV.Value = app.SV;
            app.EditField_RAP.Value = app.RAP;
            app.EditField_alpha.Value = app.a;
            app.EditField_beta.Value = app.b;
            app.EditField_tau.Value = app.tau;
            app.EditField_T_D.Value = app.T_D;
            app.EditField_MAP_eq.Value = app.MAP_eq;
            app.EditField_HR_eq.Value = app.HR_eq;
            app.EditField_TPR_eq.Value = app.TPR_eq;
            app.EditField_T.Value = app.T;
            app.EditField_V_c.Value = app.V_c;
            app.EditField_gamma.Value = app.g;
            app.EditField_D.Value = app.D;
            app.EditField_m.Value = app.m;


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Prepare simulation input
            simIn = Simulink.SimulationInput('Project2024_Group3');

            % Set variables for the simulation
            simIn = simIn.setVariable('SV', app.SV);
            simIn = simIn.setVariable('RAP', app.RAP);
            simIn = simIn.setVariable('a', app.a);
            simIn = simIn.setVariable('b', app.b);
            simIn = simIn.setVariable('tau_1', app.tau);
            simIn = simIn.setVariable('tau_2', app.tau);
            simIn = simIn.setVariable('T_D', app.T_D);
            simIn = simIn.setVariable('MAP_eq', app.MAP_eq);
            simIn = simIn.setVariable('HR_eq', app.HR_eq);
            simIn = simIn.setVariable('TPR_eq', app.TPR_eq);
            simIn = simIn.setVariable('T', app.T);
            simIn = simIn.setVariable('V_c', app.V_c);
            simIn = simIn.setVariable('g', app.g);
            simIn = simIn.setVariable('D', app.D);
            simIn = simIn.setVariable('m', app.m);

            % Run the simulation
            try
                app.SimulationOutput = sim(simIn);
                % Plot the results on the app's UIAxes
                plot_results(app, app.SimulationOutput);

                % Compute indices
                indices_table = compute_indices(app.SimulationOutput);

                % Display the table in the UITable component
                app.ResultsTable.Data = indices_table;

                % Notify the user
                uialert(app.UIFigure, 'Simulation completed successfully.', 'Success');
            catch ME
                % Handle errors
                uialert(app.UIFigure, ['Simulation failed: ' ME.message], 'Error', 'Icon', 'error');
            end
        end

        % Value changed function: EditField_SV
        function EditField_SVValueChanged(app, event)
            app.SV = app.EditField_SV.Value;
        end

        % Value changed function: EditField_RAP
        function EditField_RAPValueChanged(app, event)
            app.RAP = app.EditField_RAP.Value;
            
        end

        % Value changed function: EditField_alpha
        function EditField_alphaValueChanged(app, event)
            app.a = app.EditField_alpha.Value;
            
        end

        % Value changed function: EditField_beta
        function EditField_betaValueChanged(app, event)
            app.b = app.EditField_beta.Value;
            
        end

        % Value changed function: EditField_tau
        function EditField_tauValueChanged(app, event)
            app.tau_1 = app.EditField_tau.Value;
            
        end

        % Value changed function: EditField_T_D
        function EditField_T_DValueChanged(app, event)
            app.T_D = app.EditField_T_D.Value;
            
        end

        % Value changed function: EditField_MAP_eq
        function EditField_MAP_eqValueChanged(app, event)
            app.MAP_eq = app.EditField_MAP_eq.Value;
            
        end

        % Value changed function: EditField_HR_eq
        function EditField_HR_eqValueChanged(app, event)
            app.HR_eq = app.EditField_HR_eq.Value;
            
        end

        % Value changed function: EditField_TPR_eq
        function EditField_TPR_eqValueChanged(app, event)
            app.TPR_eq = app.EditField_TPR_eq.Value;
            
        end

        % Value changed function: EditField_T
        function EditField_TValueChanged(app, event)
            app.T = app.EditField_T.Value;
            
        end

        % Value changed function: EditField_V_c
        function EditField_V_cValueChanged(app, event)
            app.V_c = app.EditField_V_c.Value;
            
        end

        % Value changed function: EditField_gamma
        function EditField_gammaValueChanged(app, event)
            app.gamma = app.EditField_gamma.Value;
            
        end

        % Value changed function: EditField_D
        function EditField_DValueChanged(app, event)
            app.D = app.EditField_D.Value;
            
        end

        % Value changed function: EditField_m
        function EditField_mValueChanged(app, event)
            app.m = app.EditField_m.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParameterListPanel = uipanel(app.LeftPanel);
            app.ParameterListPanel.Title = 'Parameter List';
            app.SVLabel = uilabel(app.ParameterListPanel);
            app.SVLabel.Text = 'SV: ';
            app.RAPLabel = uilabel(app.ParameterListPanel);
            app.RAPLabel.Text = 'RAP: ';
            app.alphaLabel = uilabel(app.ParameterListPanel);
            app.alphaLabel.Text = 'alpha:';
            app.tau_12Label = uilabel(app.ParameterListPanel);
            app.tau_12Label.Text = 'tau_1,2: ';
            app.T_DLabel = uilabel(app.ParameterListPanel);
            app.T_DLabel.Text = 'T_D: ';
            app.MAP_eqLabel = uilabel(app.ParameterListPanel);
            app.MAP_eqLabel.Text = 'MAP_eq: ';
            app.HR_eqLabel = uilabel(app.ParameterListPanel);
            app.HR_eqLabel.Text = 'HR_eq: ';
            app.TPR_eqLabel = uilabel(app.ParameterListPanel);
            app.TPR_eqLabel.Text = 'TPR_eq: ';
            app.TLabel = uilabel(app.ParameterListPanel);
            app.TLabel.Text = 'T:';
            app.V_cLabel = uilabel(app.ParameterListPanel);
            app.V_cLabel.Text = 'V_c:';
            app.gammaLabel = uilabel(app.ParameterListPanel);
            app.gammaLabel.Text = 'gamma:';
            app.DLabel = uilabel(app.ParameterListPanel);
            app.DLabel.Text = 'D:';
            app.mLabel = uilabel(app.ParameterListPanel);
            app.mLabel.Text = 'm:';
            app.betaLabel = uilabel(app.ParameterListPanel);
            app.betaLabel.Text = 'beta:';
            app.EditField_SV = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_SV.ValueChangedFcn = createCallbackFcn(app, @EditField_SVValueChanged, true);
            app.EditField_SV.Value = 0.097;
            app.EditField_RAP = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_RAP.ValueChangedFcn = createCallbackFcn(app, @EditField_RAPValueChanged, true);
            app.EditField_RAP.Value = 5;
            app.EditField_alpha = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_alpha.ValueChangedFcn = createCallbackFcn(app, @EditField_alphaValueChanged, true);
            app.EditField_alpha.Value = 0.01;
            app.EditField_beta = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_beta.ValueChangedFcn = createCallbackFcn(app, @EditField_betaValueChanged, true);
            app.EditField_beta.Value = 0.01;
            app.EditField_tau = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_tau.ValueChangedFcn = createCallbackFcn(app, @EditField_tauValueChanged, true);
            app.EditField_tau.Value = 0.01;
            app.EditField_T_D = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_T_D.ValueChangedFcn = createCallbackFcn(app, @EditField_T_DValueChanged, true);
            app.EditField_T_D.Value = 0.002;
            app.EditField_MAP_eq = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_MAP_eq.ValueChangedFcn = createCallbackFcn(app, @EditField_MAP_eqValueChanged, true);
            app.EditField_MAP_eq.Value = 90;
            app.EditField_HR_eq = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_HR_eq.ValueChangedFcn = createCallbackFcn(app, @EditField_HR_eqValueChanged, true);
            app.EditField_HR_eq.Value = 70;
            app.EditField_TPR_eq = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_TPR_eq.ValueChangedFcn = createCallbackFcn(app, @EditField_TPR_eqValueChanged, true);
            app.EditField_TPR_eq.Value = 12.5;
            app.EditField_T = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_T.ValueChangedFcn = createCallbackFcn(app, @EditField_TValueChanged, true);
            app.EditField_T.Value = 10;
            app.EditField_V_c = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_V_c.ValueChangedFcn = createCallbackFcn(app, @EditField_V_cValueChanged, true);
            app.EditField_V_c.Value = 10;
            app.EditField_gamma = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_gammaValueChanged, true);
            app.EditField_gamma.Value = 0.25;
            app.EditField_D = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_D.ValueChangedFcn = createCallbackFcn(app, @EditField_DValueChanged, true);
            app.EditField_D.Value = 3500;
            app.EditField_m = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_m.ValueChangedFcn = createCallbackFcn(app, @EditField_mValueChanged, true);
            app.EditField_m.Value = -0.09;
            app.LLabel = uilabel(app.ParameterListPanel);
            app.LLabel.Text = 'L';
            app.mmHgLabel = uilabel(app.ParameterListPanel);
            app.mmHgLabel.Text = 'mmHg';
            app.mmHg1Label = uilabel(app.ParameterListPanel);
            app.mmHg1Label.Text = 'mmHg^-1';
            app.hrLabel = uilabel(app.ParameterListPanel);
            app.hrLabel.Text = 'hr';
            app.hrLabel_2 = uilabel(app.ParameterListPanel);
            app.hrLabel_2.Text = 'hr';
            app.mmHgLabel_2 = uilabel(app.ParameterListPanel);
            app.mmHgLabel_2.Text = 'mmHg';
            app.bpmLabel = uilabel(app.ParameterListPanel);
            app.bpmLabel.Text = 'bpm';
            app.RULabel = uilabel(app.ParameterListPanel);
            app.RULabel.Text = 'RU';
            app.hrLabel_3 = uilabel(app.ParameterListPanel);
            app.hrLabel_3.Text = 'hr';
            app.LLabel_2 = uilabel(app.ParameterListPanel);
            app.LLabel_2.Text = 'L';
            app.hr1Label = uilabel(app.ParameterListPanel);
            app.hr1Label.Text = 'hr^-1';
            app.gLabel = uilabel(app.ParameterListPanel);
            app.gLabel.Text = 'µg';
            app.RULgLabel = uilabel(app.ParameterListPanel);
            app.RULgLabel.Text = 'RU*L/µg';
            app.mmHg1Label_2 = uilabel(app.ParameterListPanel);
            app.mmHg1Label_2.Text = 'mmHg^-1';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.CpAxes = uiaxes(app.GridLayout2);
            title(app.CpAxes, 'Cp (µg/L)')
            xlabel(app.CpAxes, 'X')
            ylabel(app.CpAxes, 'Y')
            zlabel(app.CpAxes, 'Z')
            app.MAPAxes = uiaxes(app.GridLayout2);
            title(app.MAPAxes, 'MAP (mmHg)')
            xlabel(app.MAPAxes, 'X')
            ylabel(app.MAPAxes, 'Y')
            zlabel(app.MAPAxes, 'Z')
            app.TPRAxes = uiaxes(app.GridLayout2);
            title(app.TPRAxes, 'TPR (RU)')
            xlabel(app.TPRAxes, 'X')
            ylabel(app.TPRAxes, 'Y')
            zlabel(app.TPRAxes, 'Z')
            app.HRAxes = uiaxes(app.GridLayout2);
            title(app.HRAxes, 'HR (bpm)')
            xlabel(app.HRAxes, 'X')
            ylabel(app.HRAxes, 'Y')
            zlabel(app.HRAxes, 'Z')
            app.ResultsTable = uitable(app.GridLayout2);
            app.ResultsTable.ColumnName = {'Variable'; 'Equilibrium'; 'Extreme'; 'Averge %'; 'Std %'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interphase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end